classdef four < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ImportresultsButton           matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        RunSimulationButton           matlab.ui.control.Button
        TireSizeDropDown              matlab.ui.control.DropDown
        TireSizeDropDownLabel         matlab.ui.control.Label
        PerformanceMetricEditField    matlab.ui.control.EditField
        PerformanceMetricEditFieldLabel  matlab.ui.control.Label
        LoadDataButton                matlab.ui.control.Button
        AirConditionerDropDownLabel   matlab.ui.control.Label
        AirConditionerDropDown        matlab.ui.control.DropDown
        BatteryVoltageVDropDownLabel  matlab.ui.control.Label
        BatteryVoltageVDropDown       matlab.ui.control.DropDown
        FrontalAreaDropDownLabel      matlab.ui.control.Label
        FrontalAreaDropDown           matlab.ui.control.DropDown
        DragCoefficientDropDownLabel  matlab.ui.control.Label
        DragCoefficientDropDown       matlab.ui.control.DropDown
        BatterySizekwhDropDownLabel   matlab.ui.control.Label
        BatterySizekwhDropDown        matlab.ui.control.DropDown
        MotorSizeKWDropDownLabel      matlab.ui.control.Label
        MotorSizeKWDropDown           matlab.ui.control.DropDown
        PayloadinkgDropDownLabel      matlab.ui.control.Label
        PayloadinkgDropDown           matlab.ui.control.DropDown
        RegenBrakingDropDownLabel     matlab.ui.control.Label
        RegenBrakingDropDown          matlab.ui.control.DropDown
        NoofPassengersDropDownLabel   matlab.ui.control.Label
        NoofPassengersDropDown        matlab.ui.control.DropDown
        VehicleSizekgDropDownLabel    matlab.ui.control.Label
        VehicleSizekgDropDown         matlab.ui.control.DropDown
        Image2                        matlab.ui.control.Image
        FourWheelerLabel              matlab.ui.control.Label
        Image                         matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
        
            % Define the file name for saving
fileName = 'data.mat'; 
% Clear the previous data by initializing an empty .mat file
save('data.mat');

% Take input from the user and convert to appropriate types
vehicleSize = str2double(app.VehicleSizekgDropDown.Value);
passengers = str2double(app.NoofPassengersDropDown.Value);
performanceMetric = string(app.PerformanceMetricEditField.Value);
regenBraking = string(app.RegenBrakingDropDown.Value);
airconditioner = string(app.AirConditionerDropDown.Value);
payload = str2double(app.PayloadinkgDropDown.Value);
motorSize = str2double(app.MotorSizeKWDropDown.Value);
batterySize = str2double(app.BatterySizekwhDropDown.Value);
batteryVoltage = str2double(app.BatteryVoltageVDropDown.Value);
DriveSchedule = string(app.PerformanceMetricEditField.Value);

% Process tire size input to a 1x3 double array
tireSizeStr = app.TireSizeDropDown.Value;
tireSizeParts = strsplit(tireSizeStr, ',');
tireSize = str2double(tireSizeParts); % Convert to a 1x3 double array

dragCoefficient = str2double(app.DragCoefficientDropDown.Value);
frontalArea = str2double(app.FrontalAreaDropDown.Value);
VehicleType = "Car";
% Save the data to a .mat file
save(fileName, 'VehicleType', 'vehicleSize', 'passengers', 'performanceMetric', 'regenBraking', ...
     'airconditioner', 'payload', 'motorSize', 'batterySize', 'batteryVoltage', ...
     'tireSize', 'dragCoefficient', 'frontalArea', 'DriveSchedule');

% Notify the user that data is saved successfully
uialert(app.UIFigure, 'Data saved to MAT file successfully!', 'Success');


        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)

 % Specify the path to the data file
fileID = "D:\EV4\EVSim\EV\main\data.mat"; 


data = load('data.mat');


if isfield(data, 'motorSize')
    MotorSize = data.motorSize;


 else
    error('motorSize not found in the .mat file');
end
    if isfield(data, 'VehicleType')
    VehicleType = data.VehicleType;
 else
    error('VehicleType not found in the .mat file');
    end
    if isfield(data, 'airconditioner')
    AirConditioner = data.airconditioner;
 
 else
    error('airconditioner not found in the .mat file');
    end
    if isfield(data, 'batterySize')
    BatterySize = data.batterySize;
 

     else
    error('batterySize not found in the .mat file');
    end
    if isfield(data, 'batteryVoltage')
    BatteryVoltage = data.batteryVoltage;

 else
    error('batteryVoltage not found in the .mat file');
    end
    if isfield(data, 'dragCoefficient')
    DragCoefficient = data.dragCoefficient;


     else
    error('dragCoefficient not found in the .mat file');
    end
    if isfield(data, 'frontalArea')
    FrontalArea = data.frontalArea;


     else
    error('frontal area not found in the .mat file');
    end
    if isfield(data, 'passengers') 
    Passengers = data.passengers;


 else
    error('passengers not found in the .mat file');

    end
    if isfield(data, 'payload')
    Payload = data.payload;

     else
    error('paylaod not found in the .mat file');
    end
    if isfield(data, 'performanceMetric')
    PerformanceMetric = data.performanceMetric;


     else
    error('performancematric not found in the .mat file');
 
    end
 
    if isfield(data, 'regenBraking')
    RegenBraking = data.regenBraking;


     else
    error('reganbraking not found in the .mat file');
    end
    if isfield(data, 'tireSize')
    TireSize = data.tireSize;
    
     else
    error('tiresize not found in the .mat file');
    end
    if isfield(data, 'vehicleSize')
    VehicleSize = data.vehicleSize;
    
  else
    error('Vehisize not found in the .mat file');
  end

   if isfield(data, 'DriveSchedule')
    DriveSchedule = data.DriveSchedule;
    
  else
    error('DriveSchedule not found in the .mat file');
  end


if(VehicleType == "Car")
    const2 = 2;

    save(fileID, 'const2', '-append');
   
 MotorSize= 1000 * MotorSize;
   save(fileID, 'MotorSize' , '-append');
   
    BatterySize = BatterySize;  % No change needed if already in appropriate units
   
    
    NF = 2;
     NR = 2;
    save(fileID, 'NF' , '-append');
    save(fileID, 'NR' , '-append');
else
    AirConditioner = "OFF";
 save(fileID, 'AirConditioner' , '-append');

    MotorSize = 1000 * MotorSize;  % Conversion if needed
 save(fileID, 'MotorSize' , '-append');
    BatterySize = BatterySize;  % No change needed if already in appropriate units


    if(VehicleType == 'Rickshaw')
        const2 = 2;

       save(fileID, 'const2', '-append');

        NF = 1;
        NR = 2;

      save(fileID, 'NF' , '-append');
     save(fileID, 'NR' , '-append');


    elseif(VehicleType == 'Bike')
        const2 = 1;
  save(fileID, 'const1', '-append');


        NF = 1;
        NR = 1;
save(fileID, 'NF' , '-append');
     save(fileID, 'NR' , '-append');

    end
end

BatterySize = (BatterySize * 1000)/BatteryVoltage;

save(fileID, "BatterySize", '-append');


Mass = VehicleSize + Passengers * 75 + Payload;

save(fileID, "Mass", '-append');


Tire_W = TireSize(1,1);

save(fileID, "Tire_W", '-append');


AR = TireSize(1,2);

save(fileID, "AR", '-append');

Rim_d = TireSize(1,3)*25.4;% From inch to mm 

save(fileID, "Rim_d", '-append');

Tire_H = Tire_W * AR / 100;
save(fileID, "Tire_H", '-append')

Tire_d = ((Tire_H*2) + Rim_d)/1000;

save(fileID, "Tire_d", '-append');

Tire_r = Tire_d / 2;
save(fileID, "Tire_r", '-append');

if(AirConditioner == 'ON')
    const1 = 1;
    save(fileID, "const1", '-append');

else
    const1 = 2;
    save(fileID, "const2", '-append');

end

open('D:\EV4\EVSim\EV\main\EV.prj')
open('D:\EV4\EVSim\EV\main\EvReferenceApplication.slx')

%% Simulating the Simulink buildin model
for i =1:1:size(DriveSchedule,1)

save(fileID, 'i' , '-append');

    if (DriveSchedule(i,1)=='Range (km) - City')
        const = 1;

        save(fileID, 'const1' , '-append');
        ttime = 2474;
        save(fileID, 'ttime' , '-append');
        Grade = 0;
        save(fileID, 'Grade' , '-append');
        R = sim('D:\EV4\EVSim\EV\main\EvReferenceApplication.slx')
        save(fileID, 'R' , '-append');

        X1 = min([size(Vref.Data,1) size(SOC.Data,1) size(BatP.Data,1) size(BatI.Data,1) size(MotT.Data,1) size(MotS.Data,1) size(MPG.Data,1)]);
        save(fileID, 'X1' , '-append');
        ANS1 = [Vref.Time(1:X1,:) Dist.Data(1:X1,:) Vref.Data(1:X1,:) SOC.Data(1:X1,:) BatP.Data(1:X1,:) BatI.Data(1:X1,:) MotT.Data(1:X1,:) MotS.Data(1:X1,:) MPG.Data(1:X1,:)];
     
        save(fileID, 'ANS1' , '-append');
        
        X3 = ["Time","Distance","TargetVelocity","ActualVelocity","StateOfCharge","BatteryPower","BatteryCurrent","MotorTorque","MotorSpeed","MilesPerGallon"];
        save(fileID, 'X3' , '-append');
        
        T = cell2table(num2cell(ANS1),'VariableNames',cellstr(X3));
        save(fileID, 'T' , '-append');
        
        writetable(T,'D:\EV4\EVSim\EV\main\Vehicle Matlab Output_Range City.csv');
        [EV_Range] = RangeCal(ANS1(:,2),ANS1(:,5));
        save(fileID, 'EV_Range' , '-append');
        
        T1(1,1) = [EV_Range];
        T1(1,9) = [ANS1(end,2)];
    elseif(DriveSchedule(i,1)=='Top Speed (kph) x% grade')
        const = 2;
       save(fileID, 'const2' , '-append');
        
        Grade = 7;
        save(fileID, 'Grade' , '-append');
        
        ttime = 310;
        save(fileID, 'ttime' , '-append');
        R1 = sim('D:\EV4\EVSim\EV\main\EvReferenceApplication.slx')
        
        save(fileID, 'R1' , '-append');
        
        X1 = min([size(Vref.Data,1) size(SOC.Data,1) size(BatP.Data,1) size(BatI.Data,1) size(MotT.Data,1) size(MotS.Data,1) size(MPG.Data,1)]);
        save(fileID, 'X1' , '-append');
        
        ANS4 = [Vref.Time(1:X1,:) Dist.Data(1:X1,:) Vref.Data(1:X1,:) SOC.Data(1:X1,:) BatP.Data(1:X1,:) BatI.Data(1:X1,:) MotT.Data(1:X1,:) MotS.Data(1:X1,:) MPG.Data(1:X1,:)];
        save(fileID, 'ANS4' , '-append');
        
        X3 = ["Time","Distance","TargetVelocity","ActualVelocity","StateOfCharge","BatteryPower","BatteryCurrent","MotorTorque","MotorSpeed","MilesPerGallon"];
        
        save(fileID, 'X3' , '-append');
        
        T = cell2table(num2cell(ANS4),'VariableNames',cellstr(X3));
       
        save(fileID, 'T' , '-append');
        
        writetable(T,'D:\EV4\EVSim\EV\main\Vehicle Matlab Output_Top Speed x Grade.csv');
        [Vel_max] = TopSpeed(ANS4(:,4));
        T1(1,4) = [Vel_max];
        T1(1,9) = [ANS4(end,2)];
    elseif(DriveSchedule(i,1)=='Range (km) - Hwy (or Mwy)')
        const = 3;
        save(fileID, 'const3' , '-append');
        if(const2 == 1)
            ttime = 1874;
            save(fileID, 'ttime' , '-append');
        else
            ttime = 775;
            save(fileID, 'ttime' , '-append');
        end
        Grade = 0;
        save(fileID, 'Grade' , '-append');
%         ttime = 775;
        R2 = sim('D:\EV4\EVSim\EV\main\EvReferenceApplication.slx')
        
        save(fileID, 'R2' , '-append');
        
        
        X1 = min([size(Vref.Data,1) size(SOC.Data,1) size(BatP.Data,1) size(BatI.Data,1) size(MotT.Data,1) size(MotS.Data,1) size(MPG.Data,1)]);
       save(fileID, 'X1' , '-append');
        
        ANS5 = [Vref.Time(1:X1,:) Dist.Data(1:X1,:) Vref.Data(1:X1,:) SOC.Data(1:X1,:) BatP.Data(1:X1,:) BatI.Data(1:X1,:) MotT.Data(1:X1,:) MotS.Data(1:X1,:) MPG.Data(1:X1,:)];
        save(fileID, 'ANS5' , '-append');
        
        X3 = ["Time","Distance","TargetVelocity","ActualVelocity","StateOfCharge","BatteryPower","BatteryCurrent","MotorTorque","MotorSpeed","MilesPerGallon"];
        save(fileID, 'X3' , '-append');
        
        T = cell2table(num2cell(ANS5),'VariableNames',cellstr(X3));
       save(fileID, 'T' , '-append');
        writetable(T,'D:\EV4\EVSim\EV\main\Vehicle Matlab Output_Range Hwy.csv');
        [EV_Range1] = RangeCal(ANS5(:,2),ANS5(:,5));
        T1(1,2) = [EV_Range1];
        T1(1,9) = [ANS5(end,2)];
    elseif(DriveSchedule(i,1)=='Time (sec) 50 - 80 kph' || DriveSchedule(i,1)=='Top Speed (kph) 0% grade' || DriveSchedule(i,1)=='Time (sec) 0 - 100 kph' || DriveSchedule(i,1)=='Time (sec) 0.4 km')% || DriveSchedule(i,1)=='Time (sec) 50 - 80 kph')
        sim1 = sim1 + 1;
        if (sim1 == 1)
            const = 2;

            save(fileID, 'const' , '-append');
            Grade = 0;

            save(fileID, 'Grade' , '-append');
            ttime = 310;
            save(fileID, 'ttime' , '-append');
            R4 = sim('D:\EV4\EVSim\EV\main\EvReferenceApplication.slx')
            save(fileID, 'R4' , '-append');
            X1 = min([size(Vref.Data,1) size(SOC.Data,1) size(BatP.Data,1) size(BatI.Data,1) size(MotT.Data,1) size(MotS.Data,1) size(MPG.Data,1)]);
            save(fileID, 'X1' , '-append');
            
            ANS2 = [Vref.Time(1:X1,:) Dist.Data(1:X1,:) Vref.Data(1:X1,:) SOC.Data(1:X1,:) BatP.Data(1:X1,:) BatI.Data(1:X1,:) MotT.Data(1:X1,:) MotS.Data(1:X1,:) MPG.Data(1:X1,:)];
            save(fileID, 'ANS2' , '-append');
            
            X3 = ["Time","Distance","TargetVelocity","ActualVelocity","StateOfCharge","BatteryPower","BatteryCurrent","MotorTorque","MotorSpeed","MilesPerGallon"];
           
            save(fileID, 'X3' , '-append');
            T = cell2table(num2cell(ANS2),'VariableNames',cellstr(X3));
            save(fileID, 'T' , '-append');
            
            writetable(T,'D:\EV4\EVSim\EV\main\Vehicle Matlab Output_Wide Open Throtle.csv');
            T1(1,9) = [ANS2(end,2)];
        end
        if(DriveSchedule(i,1)=='Time (sec) 50 - 80 kph')
            [Time1] = Time_sec3(ANS2(:,4),ANS2(:,1));
            T1(1,7) = [Time1];
        elseif(DriveSchedule(i,1)=='Top Speed (kph) 0% grade')
            [Vel_max] = TopSpeed(ANS2(:,4));
            T1(1,3) = [Vel_max];
        elseif(DriveSchedule(i,1)=='Time (sec) 0 - 100 kph')
            [T_sec] = Time_sec1(ANS2(:,4),ANS2(:,1));
            T1(1,5) = [T_sec];
        elseif(DriveSchedule(i,1)=='Time (sec) 0.4 km')
            [T_sec1] = Time_sec2(ANS2(:,2),ANS2(:,1));
            T1(1,6) = [T_sec1];
        end
    end
end
%% Regen Braking
if(RegenBraking == 'ON')
    if(isempty(ANS1))
        const = 1;

        save(fileID, 'const' , '-append');
        ttime = 2474;

        save(fileID, 'ttime' , '-append');
        Grade = 0;

        save(fileID, 'Grade' , '-append');
        R = sim('D:\EV4\EVSim\EV\main\EvReferenceApplication.slx')
        save(fileID, 'R' , '-append');
        
        X1 = min([size(Vref.Data,1) size(SOC.Data,1) size(BatP.Data,1) size(BatI.Data,1) size(MotT.Data,1) size(MotS.Data,1) size(MPG.Data,1)]);
        save(fileID, 'X1' , '-append');
        
        ANS1 = [Vref.Time(1:X1,:) Dist.Data(1:X1,:) Vref.Data(1:X1,:) SOC.Data(1:X1,:) BatP.Data(1:X1,:) BatI.Data(1:X1,:) MotT.Data(1:X1,:) MotS.Data(1:X1,:) MPG.Data(1:X1,:)];
        save(fileID, 'ANS1' , '-append');
        
        [R1] = RegenB(ANS1(:,4),ANS1(:,6));
        save(fileID, 'R1' , '-append');
        
        T1(1,8) = R1;


    else
        [R1] = RegenB(ANS1(:,4),ANS1(:,6));
        T1(1,8) = R1;
    end
else
    T1(1,8)=0;
end
R11 = ["RangeCity","RangeHwy","TopSpeed0Grade","TopSpeedxGrade","Time0to100kph","Time400m","Time50to80kph","RegenBraking","TotalDistance"];
save(fileID, 'R11' , '-append');

T1 = [T1 zeros(1,size(R11,2)-size(T1,2))];
T1(1:end-2) = round(T1(1:end-2));
T1(1,end-1) = round(T1(1,end-1),5);
T1(1,end) = round(T1(1,end)*1.60934);
T3 = cell2table(num2cell(T1),'VariableNames',cellstr(R11));
save(fileID, 'T3' , '-append');

writetable(T3,'D:\EV4\EVSim\EV\main\PerformanceMetric.csv');
toc
fclose('all');
   uialert(app.UIFigure, 'Data saved to mat file successfully!', 'Success');


        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
          % Get the indices and new data from the edited cell
    indices = event.Indices;
    newData = event.NewData;

    % Load data from the CSV file and update UITable
    t = readtable("PerformanceMetric.csv");
    app.UITable.Data = t;
    app.UITable.ColumnName = t.Properties.VariableNames;
    
    % Aesthetic improvements
    % Set UITable properties for a more visually appealing output
    app.UITable.FontSize = 12;                       % Set a readable font size
    app.UITable.FontColor = [0, 0, 0.5];             % Set font color to a dark blue
    app.UITable.BackgroundColor = [0.95, 0.95, 1;    % Light blue alternating row color
                                   1, 1, 1];         % White row color
    app.UITable.RowStriping = 'on';                  % Enable row striping for clarity
    app.UITable.ColumnWidth = 'auto';                % Auto-size columns based on content
    app.UITable.ColumnEditable = true;               % Make the table cells editable

    % Optional: Add additional styles if needed
    % app.UITable.Tooltip = 'Edit cells to update data';   % Set a tooltip for guidance
        end

        % Button pushed function: ImportresultsButton
        function ImportresultsButtonPushed(app, event)
% This is the callback function for the button
t = readtable("PerformanceMetric.csv");
app.UITable.Data = t;
app.UITable.ColumnName = t.Properties.VariableNames;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', '2.jpg');
            app.FourWheelerLabel = uilabel(app.UIFigure);
            app.FourWheelerLabel.Text = 'Four Wheeler ';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'images', 'car.jpg');
            app.VehicleSizekgDropDown = uidropdown(app.UIFigure);
            app.VehicleSizekgDropDown.Items = {'1000', '1200', '1300', '1400', '1500', '1600'};
            app.VehicleSizekgDropDown.Value = '1000';
            app.VehicleSizekgDropDownLabel = uilabel(app.UIFigure);
            app.VehicleSizekgDropDownLabel.Text = 'Vehicle Size (kg)';
            app.NoofPassengersDropDown = uidropdown(app.UIFigure);
            app.NoofPassengersDropDown.Items = {'1', '2', '3', '4'};
            app.NoofPassengersDropDown.Value = '1';
            app.NoofPassengersDropDownLabel = uilabel(app.UIFigure);
            app.NoofPassengersDropDownLabel.Text = 'No of Passengers';
            app.RegenBrakingDropDown = uidropdown(app.UIFigure);
            app.RegenBrakingDropDown.Items = {'ON', 'OFF'};
            app.RegenBrakingDropDown.Value = 'ON';
            app.RegenBrakingDropDownLabel = uilabel(app.UIFigure);
            app.RegenBrakingDropDownLabel.Text = 'Regen Braking';
            app.PayloadinkgDropDown = uidropdown(app.UIFigure);
            app.PayloadinkgDropDown.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15'};
            app.PayloadinkgDropDown.Value = '10';
            app.PayloadinkgDropDownLabel = uilabel(app.UIFigure);
            app.PayloadinkgDropDownLabel.Text = 'Payload in kg';
            app.MotorSizeKWDropDown = uidropdown(app.UIFigure);
            app.MotorSizeKWDropDown.Items = {'10', '20'};
            app.MotorSizeKWDropDown.Value = '10';
            app.MotorSizeKWDropDownLabel = uilabel(app.UIFigure);
            app.MotorSizeKWDropDownLabel.Text = 'Motor Size (KW)';
            app.BatterySizekwhDropDown = uidropdown(app.UIFigure);
            app.BatterySizekwhDropDown.Items = {'3', '3.3'};
            app.BatterySizekwhDropDown.Value = '3';
            app.BatterySizekwhDropDownLabel = uilabel(app.UIFigure);
            app.BatterySizekwhDropDownLabel.Text = 'Battery Size (kwh)';
            app.DragCoefficientDropDown = uidropdown(app.UIFigure);
            app.DragCoefficientDropDown.Items = {'0.5', '0.6', '0.7'};
            app.DragCoefficientDropDown.Value = '0.5';
            app.DragCoefficientDropDownLabel = uilabel(app.UIFigure);
            app.DragCoefficientDropDownLabel.Text = 'Drag Coefficient';
            app.FrontalAreaDropDown = uidropdown(app.UIFigure);
            app.FrontalAreaDropDown.Items = {'0.7', '0.8', '0.9'};
            app.FrontalAreaDropDown.Value = '0.7';
            app.FrontalAreaDropDownLabel = uilabel(app.UIFigure);
            app.FrontalAreaDropDownLabel.Text = 'Frontal Area';
            app.BatteryVoltageVDropDown = uidropdown(app.UIFigure);
            app.BatteryVoltageVDropDown.Items = {'48', '50'};
            app.BatteryVoltageVDropDown.Value = '48';
            app.BatteryVoltageVDropDownLabel = uilabel(app.UIFigure);
            app.BatteryVoltageVDropDownLabel.Text = 'Battery Voltage (V)';
            app.AirConditionerDropDown = uidropdown(app.UIFigure);
            app.AirConditionerDropDown.Items = {'ON', 'OFF'};
            app.AirConditionerDropDown.Value = 'ON';
            app.AirConditionerDropDownLabel = uilabel(app.UIFigure);
            app.AirConditionerDropDownLabel.Text = 'Air Conditioner';
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.PerformanceMetricEditFieldLabel = uilabel(app.UIFigure);
            app.PerformanceMetricEditFieldLabel.Text = 'Performance Metric';
            app.PerformanceMetricEditField = uieditfield(app.UIFigure, 'text');
            app.PerformanceMetricEditField.Value = 'Range (km) - City';
            app.TireSizeDropDownLabel = uilabel(app.UIFigure);
            app.TireSizeDropDownLabel.Text = 'Tire Size';
            app.TireSizeDropDown = uidropdown(app.UIFigure);
            app.TireSizeDropDown.Items = {'120,70,12', ''};
            app.TireSizeDropDown.Value = '120,70,12';
            app.RunSimulationButton = uibutton(app.UIFigure, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Rangecity'; 'Rangehwy'; 'TopSpeed0grade'; 'TopSpeedxgrade'; '0to100kph'; 'Time400'; 'Time50to80kph'; 'RegenBraking'; 'TotalDistance'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.ImportresultsButton = uibutton(app.UIFigure, 'push');
            app.ImportresultsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportresultsButtonPushed, true);
            app.ImportresultsButton.Text = 'Import results';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = four

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end