classdef three < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ImportresultButton            matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        RunsimulationButton           matlab.ui.control.Button
        TireSizeDropDown              matlab.ui.control.DropDown
        TireSizeDropDownLabel         matlab.ui.control.Label
        PerformanceMetricEditField    matlab.ui.control.EditField
        PerformanceMetricEditFieldLabel  matlab.ui.control.Label
        LoadDataButton                matlab.ui.control.Button
        BatteryVoltageVDropDownLabel  matlab.ui.control.Label
        BatteryVoltageVDropDown       matlab.ui.control.DropDown
        FrontalAreaDropDownLabel      matlab.ui.control.Label
        FrontalAreaDropDown           matlab.ui.control.DropDown
        DragCoefficientDropDownLabel  matlab.ui.control.Label
        DragCoefficientDropDown       matlab.ui.control.DropDown
        BatterySizekwhDropDownLabel   matlab.ui.control.Label
        BatterySizekwhDropDown        matlab.ui.control.DropDown
        MotorSizeKWDropDownLabel      matlab.ui.control.Label
        MotorSizeKWDropDown           matlab.ui.control.DropDown
        PayloadkgDropDownLabel        matlab.ui.control.Label
        PayloadkgDropDown             matlab.ui.control.DropDown
        RegenBrakingDropDownLabel     matlab.ui.control.Label
        RegenBrakingDropDown          matlab.ui.control.DropDown
        NoofPassengersDropDownLabel   matlab.ui.control.Label
        NoofPassengersDropDown        matlab.ui.control.DropDown
        VehicleSizekgDropDownLabel    matlab.ui.control.Label
        VehicleSizekgDropDown         matlab.ui.control.DropDown
        Image2                        matlab.ui.control.Image
        ThreeWheelerLabel             matlab.ui.control.Label
        Image                         matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
        
    evalin('base', 'clearvars');
    vehicleSize = str2double(app.VehicleSizekgDropDown.Value);
    passengers = str2double(app.NoofPassengersDropDown.Value);
    performanceMetric = (app.PerformanceMetricEditField.Value);
    regenBraking = (app.RegenBrakingDropDown.Value);
    payload = str2double(app.PayloadkgDropDown.Value);
    motorSize = str2double(app.MotorSizeKWDropDown.Value);
    batterySize = str2double(app.BatterySizekwhDropDown.Value);
    batteryVoltage = str2double(app.BatteryVoltageVDropDown.Value);
   
% Process tire size input to a 1x3 double array
    tireSizeStr = app.TireSizeDropDown.Value;
    tireSizeParts = strsplit(tireSizeStr, ',');
    tireSize = str2double(tireSizeParts); % Convert to a 1x3 double array


    dragCoefficient = str2double(app.DragCoefficientDropDown.Value);
    frontalArea = str2double(app.FrontalAreaDropDown.Value);

    assignin('base', 'VehicleType', string('Rickshaw'));
    assignin('base', 'VehicleSize', vehicleSize);
    assignin('base', 'Passengers', passengers);
    assignin('base', 'PerformanceMetric', string(performanceMetric));
    assignin('base', 'RegenBraking', string(regenBraking));
    assignin('base', 'Payload', payload);
    assignin('base', 'MotorSize', motorSize);
    assignin('base', 'BatterySize', batterySize);
    assignin('base', 'BatteryVoltage', batteryVoltage);
    assignin('base', 'TireSize', tireSize);
    assignin('base', 'DragCoefficient', dragCoefficient);
    assignin('base', 'FrontalArea', frontalArea);

   
    uialert(app.UIFigure, 'Data saved to workspace successfully! Wait for simulation', 'Success');


        end

        % Button pushed function: RunsimulationButton
        function RunsimulationButtonPushed(app, event)
            % Define the variable name you're waiting for
variableName = 'VehicleType'; % Change this to the actual variable name you're checking for

% Continuously check if the variable exists in the workspace
while true
    % Check if the variable exists in the base workspace
    if evalin('base', ['exist(''', variableName, ''', ''var'')'])
        
        break; % Exit the loop once the variable exists
    else
       
    end
    pause(1); % Wait for 1 second before checking again (adjust as necessary)
end


tic

sim1 = 0;
assignin('base', 'sim1', (sim1));

ttime=0;
T1 = [];
assignin('base', 'T1', (T1));

T2 = [];
assignin('base', 'T2', (T2));

ANS1=[];
ANS2=[];
ANS4=[];
ANS5=[];
%% Accessing the data directly from the workspace
VehicleType = evalin('base', 'VehicleType');  
VehicleSize = evalin('base', 'VehicleSize');  
Passengers = evalin('base', 'Passengers');  
Payload = evalin('base', 'Payload');  
DriveSchedule = evalin('base', 'PerformanceMetric'); 

assignin('base', 'DriveSchedule', (DriveSchedule));

RegenBraking = evalin('base', 'RegenBraking');  
%AirConditioner = evalin('base', 'AirConditioner');  
MotorSize = evalin('base', 'MotorSize');  
BatterySize = evalin('base', 'BatterySize');  
TireSize = evalin('base', 'TireSize');  
BatteryVoltage = evalin('base', 'BatteryVoltage');  
DragCoefficient = evalin('base', 'DragCoefficient');  
FrontalArea = evalin('base', 'FrontalArea');  

if(VehicleType == "Car")
    const2 = 2;

        assignin('base', 'const2', (const2));

    MotorSize = 1000 * MotorSize;  % Conversion if needed

    assignin('base', 'MotorSize', (MotorSize));

    BatterySize = BatterySize;  % No change needed if already in appropriate units
    NF = 2;
    NR = 2;
    assignin('base', 'NF', (NF));
    assignin('base', 'NR', (NR));
else
    AirConditioner = "OFF";
    assignin('base', 'AirConditioner', string(AirConditioner));


    MotorSize = 1000 * MotorSize;  % Conversion if needed
    assignin('base', 'MotorSize', (MotorSize));
    BatterySize = BatterySize;  % No change needed if already in appropriate units
    if(VehicleType == 'Rickshaw')
        const2 = 2;
                assignin('base', 'const2', (const2));

        NF = 1;
        NR = 2;
        assignin('base', 'NF', (NF));
    assignin('base', 'NR', (NR));
    elseif(VehicleType == 'Bike')
        const2 = 1;
        assignin('base', 'const2', (const2));

        NF = 1;
        NR = 1;
        assignin('base', 'NF', (NF));
    assignin('base', 'NR', (NR));
    end
end

BatterySize = (BatterySize * 1000)/BatteryVoltage;

    assignin('base', 'BatterySize', (BatterySize));
    assignin('base', 'BatteryVoltage', (BatteryVoltage));

Mass = VehicleSize + Passengers * 75 + Payload;
assignin('base', 'Mass', (Mass));

Tire_W = TireSize(1,1);
assignin('base', 'Tire_W', (Tire_W));

AR = TireSize(1,2);
assignin('base', 'AR', (AR));

Rim_d = TireSize(1,3)*25.4;% From inch to mm 
assignin('base', 'Rim_d', (Rim_d));

Tire_H = Tire_W * AR / 100;
assignin('base', 'Tire_H', (Tire_H));
Tire_d = ((Tire_H*2) + Rim_d)/1000;
assignin('base', 'Tire_d', (Tire_d));
Tire_r = Tire_d / 2;
assignin('base', 'Tire_r', (Tire_r));
if(AirConditioner == 'ON')
 assignin('base', 'AirConditioner', string(AirConditioner));

    const1 = 1;
            assignin('base', 'const1', (const1));

else
    const1 = 2;
    assignin('base', 'const1', (const1));

end

open('D:\EV4\EVSim\EV\main\EV.prj')
open('D:\EV4\EVSim\EV\main\EvReferenceApplication.slx')

%% Simulating the Simulink buildin model
for i =1:1:size(DriveSchedule,1)

    assignin('base', 'i', (i));

    if (DriveSchedule(i,1)=='Range (km) - City')
        const = 1;
assignin('base', 'const', (const));

        ttime = 2474;
        assignin('base', 'ttime', (ttime));
        Grade = 0;
        assignin('base', 'Grade', (Grade));
        R = sim('D:\EV4\EVSim\EV\main\EvReferenceApplication.slx')
        assignin('base', 'R', (R));

        X1 = min([size(Vref.Data,1) size(SOC.Data,1) size(BatP.Data,1) size(BatI.Data,1) size(MotT.Data,1) size(MotS.Data,1) size(MPG.Data,1)]);
     
assignin('base', 'X1', (X1));

        ANS1 = [Vref.Time(1:X1,:) Dist.Data(1:X1,:) Vref.Data(1:X1,:) SOC.Data(1:X1,:) BatP.Data(1:X1,:) BatI.Data(1:X1,:) MotT.Data(1:X1,:) MotS.Data(1:X1,:) MPG.Data(1:X1,:)];
        
    assignin('base', 'ANS1', (ANS1));


        X3 = ["Time","Distance","TargetVelocity","ActualVelocity","StateOfCharge","BatteryPower","BatteryCurrent","MotorTorque","MotorSpeed","MilesPerGallon"];
       assignin('base', 'X3', (X3));
        T = cell2table(num2cell(ANS1),'VariableNames',cellstr(X3));
        assignin('base', 'T', (T));

        writetable(T,'D:\EV4\EVSim\EV\main\Vehicle Matlab Output_Range City.csv');
        [EV_Range] = RangeCal(ANS1(:,2),ANS1(:,5));
        assignin('base', 'EV_Range', (EV_Range));
        T1(1,1) = [EV_Range];
        T1(1,9) = [ANS1(end,2)];
    elseif(DriveSchedule(i,1)=='Top Speed (kph) x% grade')
        const = 2;
assignin('base', 'const', (const));

        Grade = 7;
        assignin('base', 'Grade', (Grade));

        ttime = 310;
        assignin('base', 'ttime', (ttime));
        R1 = sim('D:\EV4\EVSim\EV\main\EvReferenceApplication.slx')
        assignin('base', 'R1', (R1));

        X1 = min([size(Vref.Data,1) size(SOC.Data,1) size(BatP.Data,1) size(BatI.Data,1) size(MotT.Data,1) size(MotS.Data,1) size(MPG.Data,1)]);
       assignin('base', 'X1', (X1));
        
        ANS4 = [Vref.Time(1:X1,:) Dist.Data(1:X1,:) Vref.Data(1:X1,:) SOC.Data(1:X1,:) BatP.Data(1:X1,:) BatI.Data(1:X1,:) MotT.Data(1:X1,:) MotS.Data(1:X1,:) MPG.Data(1:X1,:)];
        X3 = ["Time","Distance","TargetVelocity","ActualVelocity","StateOfCharge","BatteryPower","BatteryCurrent","MotorTorque","MotorSpeed","MilesPerGallon"];
       assignin('base', 'X3', (X3));
        T = cell2table(num2cell(ANS4),'VariableNames',cellstr(X3));
        
        assignin('base', 'T', (T));

        writetable(T,'D:\EV4\EVSim\EV\main\Vehicle Matlab Output_Top Speed x Grade.csv');
        [Vel_max] = TopSpeed(ANS4(:,4));
        T1(1,4) = [Vel_max];
        T1(1,9) = [ANS4(end,2)];
    elseif(DriveSchedule(i,1)=='Range (km) - Hwy (or Mwy)')
        const = 3;
        assignin('base', 'const', (const));
        if(const2 == 1)
            ttime = 1874;
            assignin('base', 'ttime', (ttime));
        else
            ttime = 775;
            assignin('base', 'ttime', (ttime));
            
        end
        Grade = 0;
        assignin('base', 'Grade', (Grade));

%         ttime = 775;
assignin('base', 'ttime', (ttime));
        R2 = sim('D:\EV4\EVSim\EV\main\EvReferenceApplication.slx')

        X1 = min([size(Vref.Data,1) size(SOC.Data,1) size(BatP.Data,1) size(BatI.Data,1) size(MotT.Data,1) size(MotS.Data,1) size(MPG.Data,1)]);
        assignin('base', 'X1', (X1));
        ANS5 = [Vref.Time(1:X1,:) Dist.Data(1:X1,:) Vref.Data(1:X1,:) SOC.Data(1:X1,:) BatP.Data(1:X1,:) BatI.Data(1:X1,:) MotT.Data(1:X1,:) MotS.Data(1:X1,:) MPG.Data(1:X1,:)];
        X3 = ["Time","Distance","TargetVelocity","ActualVelocity","StateOfCharge","BatteryPower","BatteryCurrent","MotorTorque","MotorSpeed","MilesPerGallon"];
        assignin('base', 'X3', (X3));
        T = cell2table(num2cell(ANS5),'VariableNames',cellstr(X3));
        assignin('base', 'T', (T));

        
        writetable(T,'D:\EV4\EVSim\EV\main\Vehicle Matlab Output_Range Hwy.csv');
        [EV_Range1] = RangeCal(ANS5(:,2),ANS5(:,5));
        T1(1,2) = [EV_Range1];
        T1(1,9) = [ANS5(end,2)];
    elseif(DriveSchedule(i,1)=='Time (sec) 50 - 80 kph' || DriveSchedule(i,1)=='Top Speed (kph) 0% grade' || DriveSchedule(i,1)=='Time (sec) 0 - 100 kph' || DriveSchedule(i,1)=='Time (sec) 0.4 km')% || DriveSchedule(i,1)=='Time (sec) 50 - 80 kph')
    
        sim1 = sim1 + 1;
        if (sim1 == 1)
            const = 2;
            assignin('base', 'const', (const));
            Grade = 0;
            assignin('base', 'Grade', (Grade));

            ttime = 310;
            assignin('base', 'ttime', (ttime));
            R4 = sim('D:\EV4\EVSim\EV\main\EvReferenceApplication.slx')
            X1 = min([size(Vref.Data,1) size(SOC.Data,1) size(BatP.Data,1) size(BatI.Data,1) size(MotT.Data,1) size(MotS.Data,1) size(MPG.Data,1)]);
           
            assignin('base', 'X1', (X1));
            ANS2 = [Vref.Time(1:X1,:) Dist.Data(1:X1,:) Vref.Data(1:X1,:) SOC.Data(1:X1,:) BatP.Data(1:X1,:) BatI.Data(1:X1,:) MotT.Data(1:X1,:) MotS.Data(1:X1,:) MPG.Data(1:X1,:)];
            X3 = ["Time","Distance","TargetVelocity","ActualVelocity","StateOfCharge","BatteryPower","BatteryCurrent","MotorTorque","MotorSpeed","MilesPerGallon"];
            
            assignin('base', 'X3', (X3));
            T = cell2table(num2cell(ANS2),'VariableNames',cellstr(X3));
            
            assignin('base', 'T', (T));

            writetable(T,'D:\EV4\EVSim\EV\main\Vehicle Matlab Output_Wide Open Throtle.csv');
            T1(1,9) = [ANS2(end,2)];
        end
        if(DriveSchedule(i,1)=='Time (sec) 50 - 80 kph')
            [Time1] = Time_sec3(ANS2(:,4),ANS2(:,1));
            T1(1,7) = [Time1];
        elseif(DriveSchedule(i,1)=='Top Speed (kph) 0% grade')
            [Vel_max] = TopSpeed(ANS2(:,4));
            T1(1,3) = [Vel_max];
        elseif(DriveSchedule(i,1)=='Time (sec) 0 - 100 kph')
            [T_sec] = Time_sec1(ANS2(:,4),ANS2(:,1));
            T1(1,5) = [T_sec];
        elseif(DriveSchedule(i,1)=='Time (sec) 0.4 km')
            [T_sec1] = Time_sec2(ANS2(:,2),ANS2(:,1));
            T1(1,6) = [T_sec1];
        end
    end
end
%% Regen Braking
if(RegenBraking == 'ON')
   

    if(isempty(ANS1))
        const = 1;
        assignin('base', 'const', (const));
        ttime = 2474;
        assignin('base', 'ttime', (ttime));
        Grade = 0;
        assignin('base', 'Grade', (Grade));

        R = sim('D:\EV4\EVSim\EV\main\EvReferenceApplication.slx')
        assignin('base', 'R', (R));

        X1 = min([size(Vref.Data,1) size(SOC.Data,1) size(BatP.Data,1) size(BatI.Data,1) size(MotT.Data,1) size(MotS.Data,1) size(MPG.Data,1)]);
        assignin('base', 'X1', (X1));
        
        ANS1 = [Vref.Time(1:X1,:) Dist.Data(1:X1,:) Vref.Data(1:X1,:) SOC.Data(1:X1,:) BatP.Data(1:X1,:) BatI.Data(1:X1,:) MotT.Data(1:X1,:) MotS.Data(1:X1,:) MPG.Data(1:X1,:)];
        [R1] = RegenB(ANS1(:,4),ANS1(:,6));
        assignin('base', 'R1', (R1));

        T1(1,8) = R1;
    else
        [R1] = RegenB(ANS1(:,4),ANS1(:,6));
        T1(1,8) = R1;
    end
else
    T1(1,8)=0;
end
R11 = ["RangeCity","RangeHwy","TopSpeed0Grade","TopSpeedxGrade","Time0to100kph","Time400m","Time50to80kph","RegenBraking","TotalDistance"];

assignin('base', 'R11', (R11));


T1 = [T1 zeros(1,size(R11,2)-size(T1,2))];
T1(1:end-2) = round(T1(1:end-2));
T1(1,end-1) = round(T1(1,end-1),5);
T1(1,end) = round(T1(1,end)*1.60934);
T3 = cell2table(num2cell(T1),'VariableNames',cellstr(R11));
assignin('base', 'T3', (T3));

writetable(T3,'D:\EV4\EVSim\EV\main\PerformanceMetric.csv');
toc
fclose('all');


        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
        % Get the indices and new data from the edited cell
    indices = event.Indices;
    newData = event.NewData;

    % Load data from the CSV file and update UITable
    t = readtable("PerformanceMetric.csv");
    app.UITable.Data = t;
    app.UITable.ColumnName = t.Properties.VariableNames;
    
    % Aesthetic improvements
    % Set UITable properties for a more visually appealing output
    app.UITable.FontSize = 12;                       % Set a readable font size
    app.UITable.FontColor = [0, 0, 0.5];             % Set font color to a dark blue
    app.UITable.BackgroundColor = [0.95, 0.95, 1;    % Light blue alternating row color
                                   1, 1, 1];         % White row color
    app.UITable.RowStriping = 'on';                  % Enable row striping for clarity
    app.UITable.ColumnWidth = 'auto';                % Auto-size columns based on content
    app.UITable.ColumnEditable = true;               % Make the table cells editable

    % Optional: Add additional styles if needed
    % app.UITable.Tooltip = 'Edit cells to update data';   % Set a tooltip for guidance
        end

        % Button pushed function: ImportresultButton
        function ImportresultButtonPushed(app, event)
            % This is the callback function for the button
t = readtable("PerformanceMetric.csv");
app.UITable.Data = t;
app.UITable.ColumnName = t.Properties.VariableNames;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', '2.jpg');
            app.ThreeWheelerLabel = uilabel(app.UIFigure);
            app.ThreeWheelerLabel.Text = 'Three Wheeler ';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'images', 'rickshaw.jpeg');
            app.VehicleSizekgDropDown = uidropdown(app.UIFigure);
            app.VehicleSizekgDropDown.Items = {'36', '160', '170', '180', '190'};
            app.VehicleSizekgDropDown.Value = '160';
            app.VehicleSizekgDropDownLabel = uilabel(app.UIFigure);
            app.VehicleSizekgDropDownLabel.Text = 'Vehicle Size (kg)';
            app.NoofPassengersDropDown = uidropdown(app.UIFigure);
            app.NoofPassengersDropDown.Items = {'1', '2', '3', '4', '5'};
            app.NoofPassengersDropDown.Value = '1';
            app.NoofPassengersDropDownLabel = uilabel(app.UIFigure);
            app.NoofPassengersDropDownLabel.Text = 'No of Passengers';
            app.RegenBrakingDropDown = uidropdown(app.UIFigure);
            app.RegenBrakingDropDown.Items = {'ON', 'OFF'};
            app.RegenBrakingDropDown.Value = 'ON';
            app.RegenBrakingDropDownLabel = uilabel(app.UIFigure);
            app.RegenBrakingDropDownLabel.Text = 'Regen Braking';
            app.PayloadkgDropDown = uidropdown(app.UIFigure);
            app.PayloadkgDropDown.Items = {'1', '10', '15', '20', '25', '30', '35'};
            app.PayloadkgDropDown.Value = '15';
            app.PayloadkgDropDownLabel = uilabel(app.UIFigure);
            app.PayloadkgDropDownLabel.Text = 'Payload (kg)';
            app.MotorSizeKWDropDown = uidropdown(app.UIFigure);
            app.MotorSizeKWDropDown.Items = {'11', '22', '40 ', '45 ', '50'};
            app.MotorSizeKWDropDown.Value = '22';
            app.MotorSizeKWDropDownLabel = uilabel(app.UIFigure);
            app.MotorSizeKWDropDownLabel.Text = 'Motor Size (KW)';
            app.BatterySizekwhDropDown = uidropdown(app.UIFigure);
            app.BatterySizekwhDropDown.Items = {'10', '5', '2 ', '3 '};
            app.BatterySizekwhDropDown.Value = '10';
            app.BatterySizekwhDropDownLabel = uilabel(app.UIFigure);
            app.BatterySizekwhDropDownLabel.Text = 'Battery Size (kwh)';
            app.DragCoefficientDropDown = uidropdown(app.UIFigure);
            app.DragCoefficientDropDown.Items = {'0.5', '0.6', '0.7', '0.8', '0.9'};
            app.DragCoefficientDropDown.Value = '0.5';
            app.DragCoefficientDropDownLabel = uilabel(app.UIFigure);
            app.DragCoefficientDropDownLabel.Text = 'Drag Coefficient';
            app.FrontalAreaDropDown = uidropdown(app.UIFigure);
            app.FrontalAreaDropDown.Items = {'0.7', '0.8', '0.9', '1.0', '1.1', '1.2', '1.3', '1.4', '1.5'};
            app.FrontalAreaDropDown.Value = '0.7';
            app.FrontalAreaDropDownLabel = uilabel(app.UIFigure);
            app.FrontalAreaDropDownLabel.Text = 'Frontal Area';
            app.BatteryVoltageVDropDown = uidropdown(app.UIFigure);
            app.BatteryVoltageVDropDown.Items = {'48 ', '50 ', '72', '80'};
            app.BatteryVoltageVDropDown.Value = '48 ';
            app.BatteryVoltageVDropDownLabel = uilabel(app.UIFigure);
            app.BatteryVoltageVDropDownLabel.Text = 'Battery Voltage (V)';
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data ';
            app.PerformanceMetricEditFieldLabel = uilabel(app.UIFigure);
            app.PerformanceMetricEditFieldLabel.Text = 'Performance Metric';
            app.PerformanceMetricEditField = uieditfield(app.UIFigure, 'text');
            app.PerformanceMetricEditField.Value = 'Range (km) - City';
            app.TireSizeDropDownLabel = uilabel(app.UIFigure);
            app.TireSizeDropDownLabel.Text = 'Tire Size';
            app.TireSizeDropDown = uidropdown(app.UIFigure);
            app.TireSizeDropDown.Items = {'120,70,12', ''};
            app.TireSizeDropDown.Value = '';
            app.RunsimulationButton = uibutton(app.UIFigure, 'push');
            app.RunsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunsimulationButtonPushed, true);
            app.RunsimulationButton.Text = 'Run simulation';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Rangecity'; 'Rangehwy'; 'TopSpeed0grade'; 'TopSpeedxgrade'; '0to100kph'; 'Time400'; 'Time50to80kph'; 'RegenBraking'; 'TotalDistance'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.ImportresultButton = uibutton(app.UIFigure, 'push');
            app.ImportresultButton.ButtonPushedFcn = createCallbackFcn(app, @ImportresultButtonPushed, true);
            app.ImportresultButton.Text = 'Import result';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = three

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end